classdef app_tetris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        JOGOPanel                    matlab.ui.container.Panel
        MENUButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        MenuPrincipalPanel           matlab.ui.container.Panel
        COMEARJOGOButton             matlab.ui.control.Button
        AlturaEditField              matlab.ui.control.NumericEditField
        AlturaEditFieldLabel         matlab.ui.control.Label
        LarguradaBaseEditField       matlab.ui.control.NumericEditField
        LarguradaBaseEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end

    methods (Access = private)
    
    % Função que corre ao clicar em "COMEÇAR JOGO"
    function COMEARJOGOButtonPushed(app, event)
        % 1. Troca de ecrãs
        app.MenuPrincipalPanel.Visible = 'off';
        app.JOGOPanel.Visible = 'on';
        
        % 2. Ler dimensões
        n = app.LarguradaBaseEditField.Value;
        h = app.AlturaEditField.Value;
        
        % 3. Configurar o balde 3D
        cla(app.UIAxes);
        view(app.UIAxes, 3);
        grid(app.UIAxes, 'on');
        
        % Definir limites dos eixos
        app.UIAxes.XLim = [0 n];
        app.UIAxes.YLim = [0 n];
        app.UIAxes.ZLim = [0 h];
        
        % Exemplo: Desenhar um cubo de teste na base (0,0,0)
        app.desenharCubo(0, 0, 0, 'red');
    end

    % Função para voltar ao Menu
    function MENUButtonPushed(app, event)
        app.JOGOPanel.Visible = 'off';
        app.MenuPrincipalPanel.Visible = 'on';
    end

    % Função auxiliar para desenhar cubos (Cria esta em "Functions")
    function desenharCubo(app, x, y, z, cor)
        % Definição dos 8 vértices de um cubo unitário
        v = [x y z; x+1 y z; x+1 y+1 z; x y+1 z; ...
             x y z+1; x+1 y z+1; x+1 y+1 z+1; x y+1 z+1];
        
        % Definição das 6 faces (ligando os vértices)
        f = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
        
        % Desenhar o cubo nos UIAxes da App
        patch(app.UIAxes, 'Vertices', v, 'Faces', f, 'FaceColor', cor, 'EdgeColor', 'black');
    end
end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MenuPrincipalPanel = uipanel(app.UIFigure);
            app.MenuPrincipalPanel.Title = 'Menu Principal';
            app.LarguradaBaseEditFieldLabel = uilabel(app.MenuPrincipalPanel);
            app.LarguradaBaseEditFieldLabel.Text = 'Largura da Base';
            app.LarguradaBaseEditField = uieditfield(app.MenuPrincipalPanel, 'numeric');
            app.AlturaEditFieldLabel = uilabel(app.MenuPrincipalPanel);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.MenuPrincipalPanel, 'numeric');
            app.COMEARJOGOButton = uibutton(app.MenuPrincipalPanel, 'push');
            app.COMEARJOGOButton.Text = 'COMEÇAR JOGO';
            app.JOGOPanel = uipanel(app.UIFigure);
            app.JOGOPanel.Title = 'JOGO';
            app.UIAxes = uiaxes(app.JOGOPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MENUButton = uibutton(app.JOGOPanel, 'push');
            app.MENUButton.Text = 'MENU';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_tetris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end